classdef Simulation3DJoystickCalibratorDesignerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OuterGrid            matlab.ui.container.GridLayout
        RailGrid             matlab.ui.container.GridLayout
        ConnectPanel         matlab.ui.container.Panel
        ConnectPanelGrid     matlab.ui.container.GridLayout
        ConnectAccent        matlab.ui.container.Panel
        ConnectContentGrid   matlab.ui.container.GridLayout
        ConnectHeaderGrid    matlab.ui.container.GridLayout
        ConnectBadge         matlab.ui.control.Label
        ConnectTitleLabel    matlab.ui.control.Label
        ConnectBodyGrid      matlab.ui.container.GridLayout
        DeviceDropDown       matlab.ui.control.DropDown
        ConnectButton        matlab.ui.control.Button
        ConnectStatusGrid    matlab.ui.container.GridLayout
        ConnectionLamp       matlab.ui.control.Lamp
        ConnectionLabel      matlab.ui.control.Label
        CenterPanel          matlab.ui.container.Panel
        CenterPanelGrid      matlab.ui.container.GridLayout
        CenterAccent         matlab.ui.container.Panel
        CenterContentGrid    matlab.ui.container.GridLayout
        CenterHeaderGrid     matlab.ui.container.GridLayout
        CenterBadge          matlab.ui.control.Label
        CenterTitleLabel     matlab.ui.control.Label
        CenterBodyGrid       matlab.ui.container.GridLayout
        CenterButton         matlab.ui.control.Button
        SweepPanel           matlab.ui.container.Panel
        SweepPanelGrid       matlab.ui.container.GridLayout
        SweepAccent          matlab.ui.container.Panel
        SweepContentGrid     matlab.ui.container.GridLayout
        SweepHeaderGrid      matlab.ui.container.GridLayout
        SweepBadge           matlab.ui.control.Label
        SweepTitleLabel      matlab.ui.control.Label
        SweepBodyGrid        matlab.ui.container.GridLayout
        ProgressLabel        matlab.ui.control.Label
        RollRowGrid          matlab.ui.container.GridLayout
        RollSignalLamp       matlab.ui.control.Lamp
        RollSweepButton      matlab.ui.control.Button
        PitchRowGrid         matlab.ui.container.GridLayout
        PitchSignalLamp      matlab.ui.control.Lamp
        PitchSweepButton     matlab.ui.control.Button
        YawRowGrid           matlab.ui.container.GridLayout
        YawSignalLamp        matlab.ui.control.Lamp
        YawSweepButton       matlab.ui.control.Button
        ThrottleRowGrid      matlab.ui.container.GridLayout
        ThrottleSignalLamp   matlab.ui.control.Lamp
        ThrottleSweepButton  matlab.ui.control.Button
        InstructionLabel     matlab.ui.control.Label
        ProgressAxes         matlab.ui.control.UIAxes
        FooterGrid           matlab.ui.container.GridLayout
        CopyrightLabel       matlab.ui.control.Label
        WorkspaceGrid        matlab.ui.container.GridLayout
        PlotsGrid            matlab.ui.container.GridLayout
        RawAxes              matlab.ui.control.UIAxes
        NormalizedAxes       matlab.ui.control.UIAxes
        CalibrationTable     matlab.ui.control.Table
        ActionsGrid          matlab.ui.container.GridLayout
        OverviewButton       matlab.ui.control.Button
        EditButton           matlab.ui.control.Button
        ActionSpacer         matlab.ui.control.Label
        SaveButton           matlab.ui.control.Button
    end


    properties (Access = private)
        Adapter
        FigureDestroyListener
        IsShuttingDown = false
        Mode = 'hardware'
        Model
        ViewController
        ViewModel
    end

    methods (Access = public)
        function stack = getRuntimeStack(app)
            stack = struct('model', app.Model, 'viewModel', app.ViewModel, ...
                'view', app, 'adapter', app.Adapter);
        end
        function show(app)
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                app.UIFigure.Visible = 'on';
                drawnow;
            end
        end
        function shutdown(app)
            if app.IsShuttingDown
                return;
            end
            app.IsShuttingDown = true;
            listenerObject = app.FigureDestroyListener;
            app.FigureDestroyListener = [];
            if ~isempty(listenerObject)
                try
                    delete(listenerObject);
                catch
                end
            end
            controller = app.ViewController;
            app.ViewController = [];
            if ~isempty(controller)
                try
                    delete(controller);
                catch
                end
            end
            viewModel = app.ViewModel;
            app.ViewModel = [];
            if ~isempty(viewModel)
                try
                    delete(viewModel);
                catch
                end
            end
            adapter = app.Adapter;
            app.Adapter = [];
            if ~isempty(adapter)
                try
                    delete(adapter);
                catch
                end
            end
            app.Model = [];
        end
    end

    methods (Access = private)
        function initializeRuntime(app, mode)
            if ismember(string(mode), ["test", "headless"])
                adapter = Simulation3DJoystickCalibrator.Hardware.DemoJoystickAdapter();
            else
                adapter = Simulation3DJoystickCalibrator.Hardware.JoystickAdapter();
            end
            model = Simulation3DJoystickCalibrator.Models.CalibrationModel('simulation-3d-joystick');
            viewModel = Simulation3DJoystickCalibrator.ViewModels.MainViewModel(model, adapter);
            app.Mode = char(lower(string(mode)));
            app.Adapter = adapter;
            app.Model = model;
            app.ViewModel = viewModel;
            app.ViewController = Simulation3DJoystickCalibrator.Views.MainViewController(app, viewModel);
        end
        function setUnavailableState(app, message)
            app.ConnectionLamp.Color = [0.85 0.20 0.20];
            app.ConnectionLabel.Text = 'Toolbox unavailable';
            app.InstructionLabel.Text = char(string(message));
            app.DeviceDropDown.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            app.CenterButton.Enable = 'off';
            app.RollSweepButton.Enable = 'off';
            app.PitchSweepButton.Enable = 'off';
            app.YawSweepButton.Enable = 'off';
            app.ThrottleSweepButton.Enable = 'off';
            app.EditButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Simulation 3D Joystick Calibrator';
            launchMode = 'hardware';
            launchVisible = true;
            optionsKey = 'Simulation3DJoystickCalibratorLaunchOptions';
            if isappdata(groot, optionsKey)
                launchOptions = getappdata(groot, optionsKey);
                rmappdata(groot, optionsKey);
                if isfield(launchOptions, 'mode')
                    launchMode = char(lower(string(launchOptions.mode)));
                end
                if isfield(launchOptions, 'visible')
                    launchVisible = logical(launchOptions.visible);
                end
            end
            launcherFile = which('Simulation3DJoystickCalibrator');
            if ~isempty(launcherFile)
                iconFile = fullfile(fileparts(launcherFile), 'images', 'Joystick-2-AppIcon.png');
                if isfile(iconFile)
                    app.UIFigure.Icon = iconFile;
                end
            end
            app.FigureDestroyListener = addlistener(app.UIFigure, 'ObjectBeingDestroyed', @(~,~) app.shutdown());
            if isempty(which('Simulation3DJoystickCalibrator.Models.CalibrationModel'))
                app.setUnavailableState(['This toolbox installation is incomplete. Reinstall Simulation 3D Joystick ' ...
                    'Calibrator to restore the calibration services and Simulink library.']);
                app.UIFigure.Visible = 'on';
                return;
            end
            try
                app.initializeRuntime(launchMode);
            catch exception
                app.setUnavailableState('Startup failed: ' + string(exception.message));
                app.UIFigure.Visible = 'on';
                return;
            end
            if launchVisible
                app.UIFigure.Visible = 'on';
            else
                app.UIFigure.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            owner = app.UIFigure.UserData;
            if ~isempty(owner) && isa(owner, 'Simulation3DJoystickCalibratorApp') && isvalid(owner)
                delete(owner);
            else
                app.shutdown();
                delete(app);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                app.ViewController.connectCommand();
            end
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                app.ViewController.centerCommand();
            end
        end

        % Button pushed function: PitchSweepButton, RollSweepButton, 
        % ...and 2 other components
        function SweepButtonPushed(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                signalIndex = str2double(extractAfter(string(event.Source.Tag), 'SweepButton'));
                app.ViewController.signalActionCommand(signalIndex);
            end
        end

        % Cell edit callback: CalibrationTable
        function CalibrationTableCellEdit(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                app.ViewController.tableEditCommand(event);
            end
        end

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                app.ViewController.showOverview();
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                app.ViewController.loadCommand();
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.ViewController) && isvalid(app.ViewController)
                app.ViewController.saveCommand();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Simulation3DJoystickCalibratorDesignerApp';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OuterGrid = uigridlayout(app.UIFigure);
            app.WorkspaceGrid = uigridlayout(app.OuterGrid);
            app.ActionsGrid = uigridlayout(app.WorkspaceGrid);
            app.SaveButton = uibutton(app.ActionsGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tag = 'SaveButton';
            app.SaveButton.Text = 'Save';
            app.ActionSpacer = uilabel(app.ActionsGrid);
            app.ActionSpacer.Text = '';
            app.EditButton = uibutton(app.ActionsGrid, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit existing calibration';
            app.OverviewButton = uibutton(app.ActionsGrid, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Text = 'Overview';
            app.CalibrationTable = uitable(app.WorkspaceGrid);
            app.CalibrationTable.ColumnName = {'Control'; 'Channel'; 'uMin'; 'uMax'; 'Deadband'; 'Reverse'; 'Norm Min'; 'Norm Max'; 'Unit'};
            app.CalibrationTable.RowName = {};
            app.CalibrationTable.ColumnEditable = [false true true true true true true true false];
            app.CalibrationTable.CellEditCallback = createCallbackFcn(app, @CalibrationTableCellEdit, true);
            app.CalibrationTable.Tag = 'CalibrationTable';
            app.PlotsGrid = uigridlayout(app.WorkspaceGrid);
            app.NormalizedAxes = uiaxes(app.PlotsGrid);
            app.NormalizedAxes.Tag = 'NormalizedOutputPreview';
            app.RawAxes = uiaxes(app.PlotsGrid);
            app.RawAxes.Tag = 'RawChannelPreview';
            app.RailGrid = uigridlayout(app.OuterGrid);
            app.FooterGrid = uigridlayout(app.RailGrid);
            app.CopyrightLabel = uilabel(app.FooterGrid);
            app.CopyrightLabel.Text = 'Copyright 2026 The MathWorks, Inc.';
            app.SweepPanel = uipanel(app.RailGrid);
            app.SweepPanelGrid = uigridlayout(app.SweepPanel);
            app.SweepContentGrid = uigridlayout(app.SweepPanelGrid);
            app.SweepBodyGrid = uigridlayout(app.SweepContentGrid);
            app.ProgressAxes = uiaxes(app.SweepBodyGrid);
            app.ProgressAxes.Tag = 'CalibrationProgress';
            app.InstructionLabel = uilabel(app.SweepBodyGrid);
            app.InstructionLabel.Text = 'Select a device and connect.';
            app.ThrottleRowGrid = uigridlayout(app.SweepBodyGrid);
            app.ThrottleSweepButton = uibutton(app.ThrottleRowGrid, 'push');
            app.ThrottleSweepButton.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed, true);
            app.ThrottleSweepButton.Tag = 'SweepButton4';
            app.ThrottleSweepButton.Text = 'Sweep Throttle';
            app.ThrottleSignalLamp = uilamp(app.ThrottleRowGrid);
            app.YawRowGrid = uigridlayout(app.SweepBodyGrid);
            app.YawSweepButton = uibutton(app.YawRowGrid, 'push');
            app.YawSweepButton.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed, true);
            app.YawSweepButton.Tag = 'SweepButton3';
            app.YawSweepButton.Text = 'Sweep Yaw';
            app.YawSignalLamp = uilamp(app.YawRowGrid);
            app.PitchRowGrid = uigridlayout(app.SweepBodyGrid);
            app.PitchSweepButton = uibutton(app.PitchRowGrid, 'push');
            app.PitchSweepButton.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed, true);
            app.PitchSweepButton.Tag = 'SweepButton2';
            app.PitchSweepButton.Text = 'Sweep Pitch';
            app.PitchSignalLamp = uilamp(app.PitchRowGrid);
            app.RollRowGrid = uigridlayout(app.SweepBodyGrid);
            app.RollSweepButton = uibutton(app.RollRowGrid, 'push');
            app.RollSweepButton.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed, true);
            app.RollSweepButton.Tag = 'SweepButton1';
            app.RollSweepButton.Text = 'Sweep Roll';
            app.RollSignalLamp = uilamp(app.RollRowGrid);
            app.ProgressLabel = uilabel(app.SweepBodyGrid);
            app.ProgressLabel.Text = '0 of 4 verified';
            app.SweepHeaderGrid = uigridlayout(app.SweepContentGrid);
            app.SweepTitleLabel = uilabel(app.SweepHeaderGrid);
            app.SweepTitleLabel.Text = 'Sweep & verify';
            app.SweepBadge = uilabel(app.SweepHeaderGrid);
            app.SweepBadge.Text = '3';
            app.SweepAccent = uipanel(app.SweepPanelGrid);
            app.CenterPanel = uipanel(app.RailGrid);
            app.CenterPanelGrid = uigridlayout(app.CenterPanel);
            app.CenterContentGrid = uigridlayout(app.CenterPanelGrid);
            app.CenterBodyGrid = uigridlayout(app.CenterContentGrid);
            app.CenterButton = uibutton(app.CenterBodyGrid, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Tag = 'CenterButton';
            app.CenterButton.Text = 'Center & hold';
            app.CenterHeaderGrid = uigridlayout(app.CenterContentGrid);
            app.CenterTitleLabel = uilabel(app.CenterHeaderGrid);
            app.CenterTitleLabel.Text = 'Center & hold';
            app.CenterBadge = uilabel(app.CenterHeaderGrid);
            app.CenterBadge.Text = '2';
            app.CenterAccent = uipanel(app.CenterPanelGrid);
            app.ConnectPanel = uipanel(app.RailGrid);
            app.ConnectPanelGrid = uigridlayout(app.ConnectPanel);
            app.ConnectContentGrid = uigridlayout(app.ConnectPanelGrid);
            app.ConnectBodyGrid = uigridlayout(app.ConnectContentGrid);
            app.ConnectStatusGrid = uigridlayout(app.ConnectBodyGrid);
            app.ConnectionLabel = uilabel(app.ConnectStatusGrid);
            app.ConnectionLabel.Text = 'Not connected';
            app.ConnectionLamp = uilamp(app.ConnectStatusGrid);
            app.ConnectButton = uibutton(app.ConnectBodyGrid, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Tag = 'ConnectButton';
            app.ConnectButton.Text = 'Connect';
            app.DeviceDropDown = uidropdown(app.ConnectBodyGrid);
            app.DeviceDropDown.Items = {'Joystick1', 'Joystick2', 'Joystick3', 'Joystick4', 'Joystick5', 'Joystick6', 'Joystick7', 'Joystick8', 'Joystick9', 'Joystick10', 'Joystick11', 'Joystick12'};
            app.DeviceDropDown.Tag = 'DeviceDropDown';
            app.DeviceDropDown.Value = 'Joystick1';
            app.ConnectHeaderGrid = uigridlayout(app.ConnectContentGrid);
            app.ConnectTitleLabel = uilabel(app.ConnectHeaderGrid);
            app.ConnectTitleLabel.Text = 'Connect';
            app.ConnectBadge = uilabel(app.ConnectHeaderGrid);
            app.ConnectBadge.Text = '1';
            app.ConnectAccent = uipanel(app.ConnectPanelGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulation3DJoystickCalibratorDesignerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end